classdef swps1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SpaceWeatherPredictionSystemv10Label  matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        lmButton                     matlab.ui.control.Button
        brButton                     matlab.ui.control.Button
        Button_8                     matlab.ui.control.Button
        Button_10                    matlab.ui.control.Button
        scgButton                    matlab.ui.control.Button
        LButton                      matlab.ui.control.Button
        LButton_2                    matlab.ui.control.Button
        LButton_3                    matlab.ui.control.Button
        Label_5                      matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        Label_6                      matlab.ui.control.Label
        ToolsLabel                   matlab.ui.control.Label
        DescriptionLabel             matlab.ui.control.Label
        Button_11                    matlab.ui.control.Button
        Label_7                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        TextArea_3                   matlab.ui.control.TextArea
        TextArea_4                   matlab.ui.control.TextArea
        TextArea_5                   matlab.ui.control.TextArea
        TextArea_6                   matlab.ui.control.TextArea
        TextArea_7                   matlab.ui.control.TextArea
        TextArea_8                   matlab.ui.control.TextArea
        StatusBarLabel               matlab.ui.control.Label
        TextArea_9                   matlab.ui.control.TextArea
        HowtouseLabel                matlab.ui.control.Label
        Button_12                    matlab.ui.control.Button
        SolarcycleProgressinovertimeLabel  matlab.ui.control.Label
        createdbyIshaanJaiswalLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
             [file,path,indx] = uigetfile
            filename = [path file]
            global Input
            Input = importdata(filename)
            TextArea_2ValueChanged(app, event)
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file,path,indx] = uigetfile
            filename = [path file]
            global  Target 
            Target = importdata(filename)
            TextArea_3ValueChanged(app, event)
            
        end

        % Button pushed function: lmButton
        function lmButtonPushed(app, event)
            global trainFcn
            trainFcn ='trainlm';
            TextArea_6ValueChanged(app, event)
            
        end

        % Button pushed function: brButton
        function brButtonPushed(app, event)
            global trainFcn
            trainFcn ='trainbr';
            TextArea_6ValueChanged(app, event)
            
        end

        % Button pushed function: scgButton
        function scgButtonPushed(app, event)
            global trainFcn
            trainFcn ='trainscg';
            TextArea_6ValueChanged(app, event)
            
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            global hiddenLayerSize 
            hiddenLayerSize = 4;
            TextArea_5ValueChanged(app, event)
            
        end

        % Button pushed function: LButton_2
        function LButton_2Pushed(app, event)
            global hiddenLayerSize 
            hiddenLayerSize = 6;
            TextArea_5ValueChanged(app, event)
            
            
        end

        % Button pushed function: LButton_3
        function LButton_3Pushed(app, event)
            global hiddenLayerSize 
            hiddenLayerSize = 8;
            TextArea_5ValueChanged(app, event)
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            global net
            global inputDelays
            global feedbackDelays
            global hiddenLayerSize
            global trainFcn
            inputDelays = 1:2;
            feedbackDelays = 1:2;
            
            net = narxnet(inputDelays,feedbackDelays,hiddenLayerSize,'open',trainFcn);
            global x
            global xi
            global ai
            global t
            global X
            global T
            [x,xi,ai,t] = preparets(net,X,{},T);
            % Setup Division of Data for Training, Validation, Testing
            net.divideParam.trainRatio = 70/100;
            net.divideParam.valRatio = 15/100;
            net.divideParam.testRatio = 15/100;
            TextArea_7ValueChanged(app, event)
            
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            global x
            global xi
            global ai
            global t
            global net
            global tr
            global e
            global performance
            global X
            global T
            
            [net,tr] = train(net,x,t,xi,ai);
            
            y = net(x,xi,ai);
            e = gsubtract(t,y);
            performance = perform(net,t,y)
            figure, plotperform(tr)
            figure, plottrainstate(tr)
            figure, plotregression(t,y)
            figure, plotresponse(t,y)
            TextArea_8ValueChanged(app, event)
            netc = closeloop(net);
            netc.name = [net.name ' - Closed Loop'];
            view(netc)
            [xc,xic,aic,tc] = preparets(netc,X,{},T);
            yc = netc(xc,xic,aic);
            closedLoopPerformance = perform(net,tc,yc)
            nets = removedelay(net);
            nets.name = [net.name ' - Predict One Step Ahead'];
            view(nets)
            [xs,xis,ais,ts] = preparets(nets,X,{},T);
            ys = nets(xs,xis,ais);
            stepAheadPerformance = perform(nets,ts,ys)
            
            
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            global X
            global T
            global Input
            global Target
            X = tonndata(Input,true,false);
            T = tonndata(Target,true,false);
            TextArea_4ValueChanged(app, event)
            
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
             app.TextArea_2.Value{1} = 'Success';
            
        end

        % Value changed function: TextArea_3
        function TextArea_3ValueChanged(app, event)
                app.TextArea_3.Value{1} = 'Success';
            
            
        end

        % Value changed function: TextArea_4
        function TextArea_4ValueChanged(app, event)
            app.TextArea_4.Value{1} = 'Success';
            
            
        end

        % Value changed function: TextArea_6
        function TextArea_6ValueChanged(app, event)
            global trainFcn
             app.TextArea_6.Value{1} = trainFcn;


        end

        % Value changed function: TextArea_5
        function TextArea_5ValueChanged(app, event)
            global hiddenLayerSize 
             app.TextArea_5.Value{1} = int2str(hiddenLayerSize)


        end

        % Value changed function: TextArea_7
        function TextArea_7ValueChanged(app, event)
          
            app.TextArea_7.Value{1} = 'Success';
            
        end

        % Value changed function: TextArea_8
        function TextArea_8ValueChanged(app, event)
              app.TextArea_8.Value{1} = 'Success';
            
            
        end

        % Callback function
        function GetPerformancePlotButtonPushed(app, event)
            global tr
            figure, plotperform(tr)
        end

        % Callback function
        function GetRegressionPlotButtonPushed(app, event)


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SpaceWeatherPredictionSystemv10Label = uilabel(app.UIFigure);
            app.SpaceWeatherPredictionSystemv10Label.Text = 'Space Weather Prediction System       v1.0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'pngtree-vector-folder-icon-png-image_855010.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'pngtree-vector-folder-icon-png-image_855010.jpg';
            app.Button_2.Text = '';
            app.lmButton = uibutton(app.UIFigure, 'push');
            app.lmButton.ButtonPushedFcn = createCallbackFcn(app, @lmButtonPushed, true);
            app.lmButton.Text = 'lm';
            app.brButton = uibutton(app.UIFigure, 'push');
            app.brButton.ButtonPushedFcn = createCallbackFcn(app, @brButtonPushed, true);
            app.brButton.Text = 'br';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = '6461928.png';
            app.Button_8.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = 'black-up-arrow-png-22.png';
            app.Button_10.Text = '';
            app.scgButton = uibutton(app.UIFigure, 'push');
            app.scgButton.ButtonPushedFcn = createCallbackFcn(app, @scgButtonPushed, true);
            app.scgButton.Text = 'scg';
            app.LButton = uibutton(app.UIFigure, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = '4L';
            app.LButton_2 = uibutton(app.UIFigure, 'push');
            app.LButton_2.ButtonPushedFcn = createCallbackFcn(app, @LButton_2Pushed, true);
            app.LButton_2.Text = '6L';
            app.LButton_3 = uibutton(app.UIFigure, 'push');
            app.LButton_3.ButtonPushedFcn = createCallbackFcn(app, @LButton_3Pushed, true);
            app.LButton_3.Text = '8L';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Solar flares, eruptions, CMEs and other storms arising due to changes in the surface temperature and magnetic field of the Sun, can have catastrophic impacts to technological systems around or on the earth. They have been responsible for various '; 'failures to our satellites, power supplies, communications and navigation systems in the past. Damage caused to these systems have secondary effects that can easily damage '; 'and disrupt indirectly every other system and major infrastructure dependent on them, including telecommunication industry, climatic weather forecasting stations, transport sectors, defence sectors, various emergency response systems and other wireless '; 'networks and electronic devices which can lead to significant economic losses.'; 'Hence, it has become an important task for us to predict the occurrence of various solar storms and coronal mass ejections as they disrupt our Earth’s magnetic field resulting in sudden and alarming changes in the magnetosphere (the region of space surrounding Earth where the dominant magnetic field is the magnetic field of Earth) that results in '; 'generation of powerful waves and currents in the Earth’s ionosphere ( Part of Earth''s '; 'upper atmosphere, from about 80 to 600 km where ionization of atoms is caused by Extreme Ultraviolet (EUV) and x-ray solar radiation, thus creating a layer of electrons).'; 'These currents are replicated in the Earth’s conducting surface as geomagnetic induced currents. Since the critical electrical infrastructure is grounded the induced currents can '; 'hamper power grids and communication networks.'; ''};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.ToolsLabel = uilabel(app.UIFigure);
            app.ToolsLabel.Text = 'Tools';
            app.DescriptionLabel = uilabel(app.UIFigure);
            app.DescriptionLabel.Text = 'Description';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Icon = 'React-icon.svg.png';
            app.Button_11.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.ValueChangedFcn = createCallbackFcn(app, @TextArea_3ValueChanged, true);
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.ValueChangedFcn = createCallbackFcn(app, @TextArea_4ValueChanged, true);
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.ValueChangedFcn = createCallbackFcn(app, @TextArea_5ValueChanged, true);
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_6.ValueChangedFcn = createCallbackFcn(app, @TextArea_6ValueChanged, true);
            app.TextArea_7 = uitextarea(app.UIFigure);
            app.TextArea_7.ValueChangedFcn = createCallbackFcn(app, @TextArea_7ValueChanged, true);
            app.TextArea_8 = uitextarea(app.UIFigure);
            app.TextArea_8.ValueChangedFcn = createCallbackFcn(app, @TextArea_8ValueChanged, true);
            app.StatusBarLabel = uilabel(app.UIFigure);
            app.StatusBarLabel.Text = 'Status Bar:';
            app.TextArea_9 = uitextarea(app.UIFigure);
            app.TextArea_9.Value = {'Step 1: Upload the Input Dataset'; 'Step 2: Upload the Target Dataset'; 'Step 3: Click on preprocessing icon to start preprocessing.'; 'Step 4: Select the Algorithm you want to use to train your neural network from the algorithm tab.'; 'Step 5: Select the no. of hidden layers you want to use in your neural network.'; 'Step 6: Click on Create neural network.'; 'Step 5: Click on train.'; ''; 'All the status of operation will be prompted in the status bar in the bottom.'; ''; ''; 'Space Weather Prediction Application v1.0;'; 'created on 10th March 2022.'};
            app.HowtouseLabel = uilabel(app.UIFigure);
            app.HowtouseLabel.Text = 'How to use.';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.Icon = 'solar-cycle-progression (1).jpeg';
            app.Button_12.Text = '';
            app.SolarcycleProgressinovertimeLabel = uilabel(app.UIFigure);
            app.SolarcycleProgressinovertimeLabel.Text = 'Solar cycle Progressin over time ';
            app.createdbyIshaanJaiswalLabel = uilabel(app.UIFigure);
            app.createdbyIshaanJaiswalLabel.Text = 'created by Ishaan Jaiswal      ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = swps1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end